classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Group1Label                 matlab.ui.control.Label
        EEE3097SDesignProjectLabel  matlab.ui.control.Label
        RunMatlabButton             matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RunShellScriptButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunShellScriptButton
        function RunShellScriptButtonPushed(app, event)

            shell = '/Users/megan/Desktop/ece\ design\:eee3097s/EEE3097S_Group1/Milestone\ 3/Controller.sh';
            status = system(shell);
            % Check the status and display the result
            if status == 0
                disp('Shell script executed successfully.');
            else
                disp('Error executing the shell script.');
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            closereq
        end

        % Button pushed function: RunMatlabButton
        function RunMatlabButtonPushed(app, event)
           % clf(app.Image.ImageSource, 'reset');
            run("SimMain.m");
            app.Image.ImageSource = 'hyperbola_plot.png';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunShellScriptButton = uibutton(app.UIFigure, 'push');
            app.RunShellScriptButton.ButtonPushedFcn = createCallbackFcn(app, @RunShellScriptButtonPushed, true);
            app.RunShellScriptButton.Text = 'Run Shell Script';
            app.Image = uiimage(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunMatlabButton = uibutton(app.UIFigure, 'push');
            app.RunMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @RunMatlabButtonPushed, true);
            app.RunMatlabButton.Text = 'Run Matlab';
            app.EEE3097SDesignProjectLabel = uilabel(app.UIFigure);
            app.EEE3097SDesignProjectLabel.Text = 'EEE3097S Design Project';
            app.Group1Label = uilabel(app.UIFigure);
            app.Group1Label.Text = 'Group 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end